classdef js_calculator_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        JSCalculatorUIFigure       matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        JammingSourcePanel         matlab.ui.container.Panel
        PowerWEditFieldLabel       matlab.ui.control.Label
        PowerWEditField            matlab.ui.control.NumericEditField
        GaindBiEditFieldLabel      matlab.ui.control.Label
        GaindBiEditField           matlab.ui.control.NumericEditField
        WavelengthEditFieldLabel   matlab.ui.control.Label
        WavelengthEditField        matlab.ui.control.NumericEditField
        GNSSReceiverAntennaPanel   matlab.ui.container.Panel
        MinPowerdBWEditFieldLabel  matlab.ui.control.Label
        MinPowerdBWEditField       matlab.ui.control.NumericEditField
        MaxPowerdBWEditFieldLabel  matlab.ui.control.Label
        MaxPowerdBWEditField       matlab.ui.control.NumericEditField
        DistancePanel              matlab.ui.container.Panel
        MinValuemEditFieldLabel    matlab.ui.control.Label
        MinValuemEditField         matlab.ui.control.NumericEditField
        MaxValuemEditFieldLabel    matlab.ui.control.Label
        MaxValuemEditField         matlab.ui.control.NumericEditField
        IntervalEditFieldLabel     matlab.ui.control.Label
        IntervalEditField          matlab.ui.control.NumericEditField
        CalculationButton          matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        JSDistanceView             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculationButton
        function CalculationButtonPushed(app, event)
            
            % jamming configurations
            Pjt = app.PowerWEditField.Value;     % unit: W
            Gj  = app.GaindBiEditField.Value;    % unit: dB
            wavelength = app.WavelengthEditField.Value; % unit: meter
            
            % GNSS receiver antenna configurations
            Pgmin = app.MinPowerdBWEditField.Value;     % minimum received GNSS signal power in dBW
            Pgmax = app.MaxPowerdBWEditField.Value;     % maximum received GNSS signal power in dBW
            
            % Distance between jamming source and GNSSR antenna
            di   = app.IntervalEditField.Value;
            dmin = app.MinValuemEditField.Value;
            dmax = app.MaxValuemEditField.Value;
            d    = dmin:di:dmax;
            
            % __Main calculation section___
            
            % allocate variables
            js_dB = zeros(2, length(d));
            
            % free-space path loss
            FL = 10*log10( ((4*pi*d)/wavelength).^2 );
            
            % received jamming signal power
            Pjr = pow2db(Pjt) + Gj - FL;
            
            % calculate J/S in dB
            js_dB(1, :) = Pjr-Pgmin;
            js_dB(2, :) = Pjr-Pgmax;
            
            % visualize J/S versus different distance
            plot(app.JSDistanceView, ...
                d, js_dB(1, :), 'r-.', ...
                d, js_dB(2, :), 'g-.', 'LineWidth', 2);
            legend(app.JSDistanceView, ...
                {sprintf('GNSS Power = %.1f', Pgmin), sprintf('GNSS Power = %.1f', Pgmax)}, ...
                'Location', "best")
            grid(app.JSDistanceView, "on")
            xlabel(app.JSDistanceView, 'Distance (m)'), ylabel(app.JSDistanceView, 'J/S (dB)');
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.JSCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {497, 497};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {229, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.JSCalculatorUIFigure = uifigure('Visible', 'off');
            app.JSCalculatorUIFigure.Name = 'J/S Calculator';
            app.JSCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.JSCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.JammingSourcePanel = uipanel(app.LeftPanel);
            app.JammingSourcePanel.Title = 'Jamming Source';
            app.PowerWEditFieldLabel = uilabel(app.JammingSourcePanel);
            app.PowerWEditFieldLabel.Text = 'Power (W)';
            app.PowerWEditField = uieditfield(app.JammingSourcePanel, 'numeric');
            app.PowerWEditField.Value = 10;
            app.GaindBiEditFieldLabel = uilabel(app.JammingSourcePanel);
            app.GaindBiEditFieldLabel.Text = 'Gain (dBi)';
            app.GaindBiEditField = uieditfield(app.JammingSourcePanel, 'numeric');
            app.GaindBiEditField.Value = 20;
            app.WavelengthEditFieldLabel = uilabel(app.JammingSourcePanel);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.JammingSourcePanel, 'numeric');
            app.WavelengthEditField.Value = 0.19;
            app.GNSSReceiverAntennaPanel = uipanel(app.LeftPanel);
            app.GNSSReceiverAntennaPanel.Title = 'GNSS Receiver Antenna';
            app.MinPowerdBWEditFieldLabel = uilabel(app.GNSSReceiverAntennaPanel);
            app.MinPowerdBWEditFieldLabel.Text = 'Min. Power (dBW)';
            app.MinPowerdBWEditField = uieditfield(app.GNSSReceiverAntennaPanel, 'numeric');
            app.MinPowerdBWEditField.Value = -158.5;
            app.MaxPowerdBWEditFieldLabel = uilabel(app.GNSSReceiverAntennaPanel);
            app.MaxPowerdBWEditFieldLabel.Text = 'Max. Power (dBW)';
            app.MaxPowerdBWEditField = uieditfield(app.GNSSReceiverAntennaPanel, 'numeric');
            app.MaxPowerdBWEditField.Value = -153.5;
            app.DistancePanel = uipanel(app.LeftPanel);
            app.DistancePanel.Title = 'Distance';
            app.MinValuemEditFieldLabel = uilabel(app.DistancePanel);
            app.MinValuemEditFieldLabel.Text = 'Min. Value (m)';
            app.MinValuemEditField = uieditfield(app.DistancePanel, 'numeric');
            app.MinValuemEditField.Limits = [0 Inf];
            app.MaxValuemEditFieldLabel = uilabel(app.DistancePanel);
            app.MaxValuemEditFieldLabel.Text = 'Max. Value (m)';
            app.MaxValuemEditField = uieditfield(app.DistancePanel, 'numeric');
            app.MaxValuemEditField.Value = 1000;
            app.IntervalEditFieldLabel = uilabel(app.DistancePanel);
            app.IntervalEditFieldLabel.Text = 'Interval';
            app.IntervalEditField = uieditfield(app.DistancePanel, 'numeric');
            app.IntervalEditField.Value = 10;
            app.CalculationButton = uibutton(app.LeftPanel, 'push');
            app.CalculationButton.ButtonPushedFcn = createCallbackFcn(app, @CalculationButtonPushed, true);
            app.CalculationButton.Text = 'Calculation';
            app.RightPanel = uipanel(app.GridLayout);
            app.JSDistanceView = uiaxes(app.RightPanel);
            title(app.JSDistanceView, 'J/S v.s. Distance')
            xlabel(app.JSDistanceView, 'Distance (m)')
            ylabel(app.JSDistanceView, 'J/S (dB)')
            % Show the figure after all components are created
            app.JSCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = js_calculator_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.JSCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.JSCalculatorUIFigure)
        end
    end
end